classdef BrainViewer_MovieMaker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GenerateMovieButton      matlab.ui.control.Button
        ElevationEditField       matlab.ui.control.NumericEditField
        ElevationEditFieldLabel  matlab.ui.control.Label
        AzimuthEditField         matlab.ui.control.NumericEditField
        AzimuthEditFieldLabel    matlab.ui.control.Label
        RotateDropDown           matlab.ui.control.DropDown
        RotateDropDownLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp
    end
    
    properties (Access = private)
        Property2 % Description
    end
    
    methods (Access = private)
        
        function rotateAxes(app)
            app.CallingApp.RotateParameters.Azimuth = app.AzimuthEditField.Value;
            app.CallingApp.RotateParameters.Elevation = app.ElevationEditField.Value;
            view(app.CallingApp.UIAxes,app.AzimuthEditField.Value,app.ElevationEditField.Value)
            app.CallingApp.Light.Position = app.CallingApp.UIAxes.CameraPosition;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
            if nargin==2
                app.CallingApp = callingApp;
                app.ElevationEditField.Value = app.CallingApp.RotateParameters.Elevation;
                app.AzimuthEditField.Value = app.CallingApp.RotateParameters.Azimuth;
            end
        end

        % Value changed function: AzimuthEditField
        function AzimuthEditFieldValueChanged(app, event)
            rotateAxes(app)
        end

        % Value changed function: ElevationEditField
        function ElevationEditFieldValueChanged(app, event)
            rotateAxes(app)
        end

        % Button pushed function: GenerateMovieButton
        function GenerateMovieButtonPushed(app, event)
            formats = {'*.gif','GIF (*.gif)'};
            [filename,path] = uiputfile(formats,'Save GIF');
            if filename==0
                return
            end
%             if exist(fullfile(path,filename),'file')
%                 ok = uiconfirm(app.UIFigure,'The image file already exists. Would you like to overwrite?','Overwrite?');
%                 if strcmp(ok,'No')
%                     return
%                 end
%             end
            
            d = uiprogressdlg(app.UIFigure);
            rotation = 360;
            F(rotation) = struct('cdata',[],'colormap',[]);
            for i = 1:rotation
                d.Value = i/rotation;
                d.Message = 'Collecting Images';
                switch app.RotateDropDown.Value
                    case 'Clockwise'
                        app.AzimuthEditField.Value = app.AzimuthEditField.Value + 1;
                    case 'Counter-Clockwise'
                        app.AzimuthEditField.Value = app.AzimuthEditField.Value - 1;
                end
                rotateAxes(app)
                F(i) = getframe(app.CallingApp.UIFigure);
            end
            
            d.Indeterminate = 'on';
            d.Message = 'Writing Frames to GIF';
            for i = 1:numel(F)
                A = frame2im(F(i));
                [B,map] = rgb2ind(A,256);
                if i == 1
                    imwrite(B,map,fullfile(path,filename),'gif','LoopCount',Inf,'DelayTime',0.02);
                else
                    imwrite(B,map,fullfile(path,filename),'gif','WriteMode','append','DelayTime',0.02);
                end
            end
            close(d)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RotateDropDownLabel = uilabel(app.UIFigure);
            app.RotateDropDownLabel.Text = 'Rotate';
            app.RotateDropDown = uidropdown(app.UIFigure);
            app.RotateDropDown.Items = {'Clockwise', 'Counter-Clockwise'};
            app.RotateDropDown.Value = 'Clockwise';
            app.AzimuthEditFieldLabel = uilabel(app.UIFigure);
            app.AzimuthEditFieldLabel.Text = 'Azimuth';
            app.AzimuthEditField = uieditfield(app.UIFigure, 'numeric');
            app.AzimuthEditField.ValueChangedFcn = createCallbackFcn(app, @AzimuthEditFieldValueChanged, true);
            app.ElevationEditFieldLabel = uilabel(app.UIFigure);
            app.ElevationEditFieldLabel.Text = 'Elevation';
            app.ElevationEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElevationEditField.ValueChangedFcn = createCallbackFcn(app, @ElevationEditFieldValueChanged, true);
            app.GenerateMovieButton = uibutton(app.UIFigure, 'push');
            app.GenerateMovieButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMovieButtonPushed, true);
            app.GenerateMovieButton.Text = 'Generate Movie';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BrainViewer_MovieMaker(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end